classdef Postural < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PosturalUIFigure         matlab.ui.Figure
        BBPanel                  matlab.ui.container.Panel
        BBAxes                   matlab.ui.control.UIAxes
        BB1LTLabel               matlab.ui.control.Label
        BB1LBLabel               matlab.ui.control.Label
        BB1RTLabel               matlab.ui.control.Label
        BB1RBLabel               matlab.ui.control.Label
        BB2LTLabel               matlab.ui.control.Label
        BB2LBLabel               matlab.ui.control.Label
        BB2RTLabel               matlab.ui.control.Label
        BB2RBLabel               matlab.ui.control.Label
        BB1SumLabel              matlab.ui.control.Label
        BB2SumLabel              matlab.ui.control.Label
        BBCopyToClipboardButton  matlab.ui.control.Button
        ZznamPanel               matlab.ui.container.Panel
        DlkaLabel                matlab.ui.control.Label
        RecordLengthSpinner      matlab.ui.control.Spinner
        vteinLabel               matlab.ui.control.Label
        ProgressSlider           matlab.ui.control.Slider
        OpenLastRecordButton     matlab.ui.control.Button
        RecordButton             matlab.ui.control.Button
        RCPanel                  matlab.ui.container.Panel
        RCAxes                   matlab.ui.control.UIAxes
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Cv7Tv2Caption            matlab.ui.control.Label
        Cv7Tv2Label              matlab.ui.control.Label
        Cv7Tv2Caption_2          matlab.ui.control.Label
        Tv2Tv7Label              matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        Label_10                 matlab.ui.control.Label
        Tv7Lv1Caption            matlab.ui.control.Label
        Tv7Lv1Label              matlab.ui.control.Label
        Lv1Lv3Caption            matlab.ui.control.Label
        Lv1Lv3Label              matlab.ui.control.Label
        Label_11                 matlab.ui.control.Label
        Lv3Lv5Caption            matlab.ui.control.Label
        Lv3Lv5Label              matlab.ui.control.Label
        RCCopyToClipboardButton  matlab.ui.control.Button
        DepthMapSnapshotButton   matlab.ui.control.Button
        SCPanel                  matlab.ui.container.Panel
        SCAxes                   matlab.ui.control.UIAxes
        Label_12                 matlab.ui.control.Label
        Label_13                 matlab.ui.control.Label
        RcajRiasCaption          matlab.ui.control.Label
        RcajTv7Label             matlab.ui.control.Label
        RcajRipsCaption          matlab.ui.control.Label
        RcajSxsLabel             matlab.ui.control.Label
        Label_14                 matlab.ui.control.Label
        RiasRipsCaption          matlab.ui.control.Label
        Tv7SxsLabel              matlab.ui.control.Label
        SCCopyToClipboardButton  matlab.ui.control.Button
        PrhSliderLabel           matlab.ui.control.Label
        GreenThresholdSlider     matlab.ui.control.Slider
        Label_15                 matlab.ui.control.Label
        RcajRiasCaption_2        matlab.ui.control.Label
        RcajRiasLabel            matlab.ui.control.Label
        RcajRipsCaption_2        matlab.ui.control.Label
        RipsRiasLabel            matlab.ui.control.Label
        Label_16                 matlab.ui.control.Label
        RiasRipsCaption_2        matlab.ui.control.Label
        RcajRipsLabel            matlab.ui.control.Label
        Label_17                 matlab.ui.control.Label
        RCLegend                 matlab.ui.control.UIAxes
        KoprovatPanel            matlab.ui.container.Panel
        CopyMainButton           matlab.ui.control.Button
        CopyAllButton            matlab.ui.control.Button
    end


    properties (Access = private)
        Timer                   % Casovac
        BB1                     % Prvni balancni plosina
        BB1Tara                 % Tara prvni plosiny
        BB2                     % Druha balancni plosina
        BB2Tara                 % Tara druhe plosiny  
        LastBB1Val = nan(1, 4)  % Posledni namerena velicina z prvni plosiny
        LastBB2Val = nan(1, 4)  % Posledni namerena velicina z druhe plosiny
        LastRCVal = nan(1, 17)  % Posledni spravne namerene hodnoty ze zadni kamery
        LastSCVal = nan(1, 9)   % Posledni spravne namerene hodnoty z postranni kamery
        RCMarkerColors = {
            [148 46 13]/255, [87 222 255]/255, [34 177 76]/255, ...
            [237 28 36]/255, [63 72 204]/255, [255 127 39]/255};
        SCMarkerColors = {
            [163 73 164]/255, [63 72 204]/255, [255 127 39]/255, ...
            [56 148 182]/255, [255 255 0]/255};
    end

    properties (Access = public)
        FPS = 10             % Pocet snimku za vterinu
        SaveBuffer = []      % Buffer pro ukladani dat, ktera budou ulozena
        Recording = 0        % 1 pokud prave bezi nahravani
        RecBegin = NaN       % Cas zacatku nahravani (vysledek funkce now)
        RecLastSample = NaN  % Cas posledniho vzorku (vysledek funkce now)
        LastRecordFile = ""  % Soubor, ve kterem je ulozeny posledni zaznam
        RCMask               % Maska pro marker zadní kamery
        RCDepthVid           % Ukazatel na zdroj hloubkove mapy zadni kamery
        RCDepthMap           % Buffer pro ukladani hloubkove mapy
        RCDepthImShowRes = 0
        RCPlotHandles = []   %
        SCColorVid           % Ukazatel na zdroj obrazu postranni kamery
        SCColorImage         % Buffer pro ukladani barevneho obrazu
        SCColorImShowRes = 0
        SCPlotHandles = []
        GreenThreshold = 0  % Práh zelených markerů
    end

    methods (Access = public)
    
        function onTimer(app)
            t = now * 86400;
            index = nan;
            
            if app.Recording
                if isnan(app.RecBegin)
                    app.RecBegin = t;
                end
                
                relTime = t - app.RecBegin;
                
                index = app.relTimeToIndex(relTime);
                
                if index > size(app.SaveBuffer, 1)
                    app.stopRecording();
                    
                    return;
                end
                
                app.RecLastSample = index;
                
                app.ProgressSlider.Value = round(relTime);
                
                app.SaveBuffer(index, 1) = relTime;
            end
            
            updateBB(app, index);
            
            updateRC(app, index);
            
            updateSC(app, index);
            
            drawnow;
        end
        
    end

    methods (Access = private)
        function allocateBuffer(app)
            bufLength = max(app.RecordLengthSpinner.Value * app.FPS, 1);
            
            app.SaveBuffer = NaN(bufLength, 48);
        end
        
        function res = relTimeToIndex(app, t)
            res = floor(t * app.FPS) + 1;
        end
    
        function depthMapSnapshot(app)
            currentpath = fileparts(mfilename('fullpath'));
            
            if 7==exist([currentpath '/../data/'],'dir')
                currentpath = [currentpath '/../data'];
            end
            
            [filename, pathname] = uiputfile([currentpath, '/', '*.mat'], 'Uložení 3D mapy');
            
            figure(app.PosturalUIFigure); % Gives focus to app window
            
            if isequal(filename, 0)
                return
            end
            
            fullname = [pathname filename];

            backDepthMap = app.RCDepthMap;
                       
            save(fullname, 'backDepthMap');
            
            backDepthMap(backDepthMap<500|backDepthMap>1300) = nan;

            figure;
            
            mesh(backDepthMap);
            
            xlim([1 512]);
            ylim([1 424]);
            zlim([500 1300]);
            view([180 90]);
        end
        
        function saveRecord(app)
            currentpath = fileparts(mfilename('fullpath'));
            
            if 7==exist([currentpath '/../data/'],'dir')
                currentpath = [currentpath '/../data'];
            end
            
            [filename, pathname] = uiputfile([currentpath, '/', '*.xlsx'], 'Export dat do XLSX souboru');
            
            figure(app.PosturalUIFigure); % Gives focus to app window
            
            if isequal(filename, 0)
                return
            end
            
            fullname = [pathname filename];
            app.LastRecordFile = fullname;
            
            buf = app.SaveBuffer(:, :);
            buf(all(isnan(buf), 2), :) = [];
            
            tblToStore = table(...
                round(buf(:,1), 2), ... % Cas
                round(buf(:,5), 1), ... % Balancni plosiny
                round(buf(:,4), 1), ...
                round(buf(:,3), 1), ...
                round(buf(:,2), 1), ...
                round(buf(:,6), 1), ...
                round(buf(:,7), 1), ...
                round(buf(:,8), 1), ...
                round(buf(:,9), 1), ...
                round(buf(:,10)), ...
                round(buf(:,11)), ...
                round(buf(:,12)), ...
                round(buf(:,13)), ...
                round(buf(:,14)), ...
                round(buf(:,15)), ...
                round(buf(:,16), 1), ... % Zadni kamera
                round(buf(:,17), 1), ...
                round(buf(:,18), 1), ...
                round(buf(:,19), 1), ...
                round(buf(:,20), 1), ...
                round(buf(:,21), 1), ...
                round(buf(:,22), 1), ...
                round(buf(:,23), 1), ...
                round(buf(:,24), 1), ...
                round(buf(:,25), 1), ...
                round(buf(:,26), 1), ...
                round(buf(:,27), 1), ...
                round(buf(:,28), 1), ...
                round(buf(:,29), 1), ...
                round(buf(:,30), 1), ...
                round(buf(:,31), 1), ...
                round(buf(:,32), 1), ...
                round(buf(:,33), 1), ... % Bocni kamera
                round(buf(:,34), 1), ...
                round(buf(:,35), 1), ...
                round(buf(:,36), 1), ...
                round(buf(:,37), 1), ...
                round(buf(:,38), 1), ...
                round(buf(:,39), 1), ...
                round(buf(:,40), 1), ...
                round(buf(:,41), 1), ...
                round(buf(:,42), 1), ...
                round(buf(:,43), 1), ...
                round(buf(:,44), 1), ...
                round(buf(:,45), 1), ...
                round(buf(:,46), 1), ...
                round(buf(:,47), 1), ...
                round(buf(:,48), 1), ...
                'VariableNames', ...
                 {'Cas', ...
                 'BbLLT', 'BbLLB', 'BbLRT', 'BbLRB', ...
                 'BbRLT', 'BbRLB', 'BbRRT', 'BbRRB', ...
                 'LCopX', 'LCopY', 'RCopX', 'RCopY', ...
                 'CogX', 'CogY', ...
                 'Cv7X', 'Cv7Y', 'Tv2X', 'Tv2Y', 'Tv7X', 'Tv7Y', ...
                 'Lv1X', 'Lv1Y', 'Lv3X', 'Lv3Y', 'Lv5X', 'Lv5Y', ...
                 'Cv7Tv2', 'Tv2Tv7', 'Tv7Lv1', 'Lv1Lv3', 'Lv3Lv5', ...
                 'RcajX', 'RcajY', ...
                 'SxsX',  'SxsY', ...
                 'Tv7sX', 'Tv7sY', ...
                 'RiasX', 'RiasY', ...
                 'RipsX', 'RipsY', ...
                 'RcajSxs', 'RcajTv7', ...
                 'Tv7Sxs', 'RcajRips', ...
                 'RcajRias', 'RipsRias'
                 });
            
            if exist(fullname, 'file')==2
                delete(fullname);
            end
                 
            writetable(tblToStore, fullname);
        end
        
        function initBB(app)
            bb1 = actxserver('WiiLab.WiiLAB');
            %bb2 = actxserver('WiiLab.WiiLAB');
            bb1.Connect();
            %bb2.Connect();
            
            app.BB1 = bb1;
            app.BB1Tara = bb1.GetBalanceBoardSensorState();
            %app.BB2 = bb2;
            %app.BB2Tara = bb2.GetBalanceBoardSensorState();
        end
        
        function initRC(app)
            %depthVid = videoinput('kinect',4);
            %triggerconfig(depthVid,'manual');
            %depthVid.FramesPerTrigger = 1;
            %depthVid.TriggerRepeat = inf;
            
            h = fspecial('gaussian',[10 10], 5);
            h = h(2:end,2:end);
            mask = -ones(15);
            mask(4:12,4:12) = h;
            app.RCMask = mask;
            
            title(app.RCAxes, []);
            xlabel(app.RCAxes, []);
            ylabel(app.RCAxes, []);
            app.RCAxes.XAxis.TickLabels = {};
            app.RCAxes.YAxis.TickLabels = {};

            title(app.RCLegend, []);
            xlabel(app.RCLegend, []);
            ylabel(app.RCLegend, []);
            app.RCLegend.XAxis.TickLabels = {};
            app.RCLegend.YAxis.TickLabels = {};
            
            %imshow([fileparts(mfilename('fullpath')) '/../resources/spine.png'], 'Parent', app.RCLegend, ...
              %  'XData', [1 app.RCLegend.Position(3)], 'YData', [1 app.RCLegend.Position(4)]);
            
            %app.RCDepthVid = depthVid;
            app.RCDepthMap = zeros(424, 512);            
            %start(depthVid);
        end
                        
        function initSC(app)
            %colorVid = videoinput('kinect',1,'RGB_640x480');
            %triggerconfig(colorVid,'manual');
            %colorVid.FramesPerTrigger = 1;
            %colorVid.TriggerRepeat = inf;
            
            title(app.SCAxes, []);
            xlabel(app.SCAxes, []);
            ylabel(app.SCAxes, []);
            app.SCAxes.XAxis.TickLabels = {};
            app.SCAxes.YAxis.TickLabels = {};
            
            %app.SCColorVid = colorVid;
            app.SCColorImage = uint8(zeros(480, 640, 3));

            %start(colorVid);
        end
        
        function initTimer(app)
            tim = timer('Period', 0.1, 'ExecutionMode', 'fixedRate');
            tim.TimerFcn = @(obj, event)onTimer(app);
            tim.StartDelay = 2;
            app.Timer = tim;            

            start(tim);           
        end
        
        function closeTimer(app)
            tim = app.Timer;
            if ~isempty(tim) && strcmp(tim.Running, 'on')
                stop(tim);
            end
            
            delete(tim);            
        end
        
        function closeBB(app)
            if ~isempty(app.BB1)
                app.BB1.Disconnect();
            end
            if ~isempty(app.BB2)
                app.BB2.Disconnect();            
            end
        end
               
        function closeRC(app)
            if ~isempty(app.RCDepthVid)
                stop(app.RCDepthVid);
                delete(app.RCDepthVid);
            end
        end   
        
        function closeSC(app)
            if ~isempty(app.SCColorVid)
                stop(app.SCColorVid);
                delete(app.SCColorVid);
            end
        end
    
        function updateBB(app, index)
            bb1Val = (app.BB1.GetBalanceBoardSensorState() - app.BB1Tara) / 4;
            bb2Val = (app.BB2.GetBalanceBoardSensorState() - app.BB2Tara) / 4;

            app.LastBB1Val = bb1Val;
            app.LastBB2Val = bb2Val;
                      
            [leftCopX, leftCopY] = cop(bb1Val, 1);
            [rightCopX, rightCopY] = cop(bb2Val, 2);
            
            [cogX, cogY] = cog(bb1Val, bb2Val);
            
            if ~isnan(index)
                app.SaveBuffer(index, 2:15) = [ 
                    bb1Val bb2Val ...
                    leftCopX  leftCopY ...
                    rightCopX, rightCopY ...
                    cogX, cogY ...
                ];
            end
                    
            vizualizeBB(app, bb1Val, bb2Val, ...
                leftCopX, leftCopY, rightCopX, rightCopY, ...
                cogX, cogY, ~isnan(index));
        end
        
        function vizualizeBB(app, bb1Val, bb2Val, ...
                leftCopX, leftCopY, rightCopX, rightCopY, ...
                cogX, cogY, recording)
            bb1Sum = sum(bb1Val);
            bb2Sum = sum(bb2Val);
            app.BB1LTLabel.Text = sprintf('%.1f', bb1Val(4));
            app.BB1LBLabel.Text = sprintf('%.1f', bb1Val(3));
            app.BB1RTLabel.Text = sprintf('%.1f', bb1Val(2));
            app.BB1RBLabel.Text = sprintf('%.1f', bb1Val(1));
            app.BB1SumLabel.Text = sprintf('%.1f kg', bb1Sum);
            app.BB2LTLabel.Text = sprintf('%.1f', bb2Val(1));
            app.BB2LBLabel.Text = sprintf('%.1f', bb2Val(2));
            app.BB2RTLabel.Text = sprintf('%.1f', bb2Val(3));
            app.BB2RBLabel.Text = sprintf('%.1f', bb2Val(4));
            app.BB2SumLabel.Text = sprintf('%.1f kg', bb2Sum);

            if recording
%                cla(app.BBAxes);
            else
                hold(app.BBAxes, 'off');
                axis(app.BBAxes, [-300 300 -300 300]);
                plot(app.BBAxes, [-38.5 -38.5 -276.5 -276.5 -38.5],[216.5 -216.5 -216.5 216.5 216.5],':r');
                hold(app.BBAxes, 'on');
                plot(app.BBAxes, [38.5 38.5 276.5 276.5 38.5],[216.5 -216.5 -216.5 216.5 216.5],':b');
    
                plot(app.BBAxes, leftCopX, leftCopY,'*r');
                hold(app.BBAxes, 'on');
                plot(app.BBAxes, rightCopX, rightCopY,'*b');
                plot(app.BBAxes, cogX, cogY,'og');
            end            
        end
               
        function updateRC(app, index)
            depthVid = app.RCDepthVid;
            
            [app.RCDepthMap(:,:)] = getsnapshot(depthVid);
            app.RCDepthMap(:,:) = fliplr(app.RCDepthMap);
            
            centroid = markerDepthDetector(app.RCDepthMap, app.RCMask);
            
            if length(centroid) == 12 % Bylo detekovano prave 6 markeru
                rankTagDepth = tagRCDepth(centroid);
                angles = countRCAngles(centroid, rankTagDepth);
                
                app.LastRCVal = [
                    centroid(rankTagDepth(1)*2+[-1 0]) ...
                    centroid(rankTagDepth(2)*2+[-1 0]) ...
                    centroid(rankTagDepth(3)*2+[-1 0]) ...
                    centroid(rankTagDepth(4)*2+[-1 0]) ...
                    centroid(rankTagDepth(5)*2+[-1 0]) ...
                    centroid(rankTagDepth(6)*2+[-1 0]) ...
                    angles];
            
                if ~isnan(index)
                    app.SaveBuffer(index, 16:32) = [ 
                        app.LastRCVal
                    ];
                end   
            else
                app.LastRCVal = nan(1, 17);
            end
    
            if isnan(index)
                if app.RCDepthImShowRes==0
                    app.RCDepthImShowRes = imshow(app.RCDepthMap, [0 4096], 'Parent', app.RCAxes);
                    colormap(app.RCAxes, jet);
                    hold(app.RCAxes, 'on');
                else
                    set(app.RCDepthImShowRes,'Cdata',app.RCDepthMap);
                end
            else
                app.RCDepthImShowRes = 0;
            end

            if ~isempty(app.RCPlotHandles)
                delete(app.RCPlotHandles);
                app.RCPlotHandles = [];
            end
            
            if length(centroid) == 12 % Bylo detekovano prave 6 markeru
                if isnan(index)
                    app.RCPlotHandles(1) = plot(app.RCAxes, centroid(rankTagDepth*2-1),centroid(rankTagDepth*2), 'LineWidth', 2, 'Color', [.97 .61 .36]);
                    
                    for i = 1:length(centroid)/2
                        app.RCPlotHandles(i+1) = plot(app.RCAxes, centroid(rankTagDepth(i)*2-1),centroid(rankTagDepth(i)*2), '*', 'Color', app.RCMarkerColors{i},'MarkerSize',6);
                    end 
                else
%                    cla(app.RCAxes);
                end
                
                app.Cv7Tv2Label.Text = sprintf('%.1f', angles(1));
                app.Tv2Tv7Label.Text = sprintf('%.1f', angles(2));
                app.Tv7Lv1Label.Text = sprintf('%.1f', angles(3));
                app.Lv1Lv3Label.Text = sprintf('%.1f', angles(4));
                app.Lv3Lv5Label.Text = sprintf('%.1f', angles(5));
        
            else
                if isnan(index)
                    for i = 1:length(centroid)/2
                        app.RCPlotHandles(i) = plot(app.RCAxes, centroid(i*2-1), centroid(i*2), 'b*', 'MarkerSize',6);
                    end
                else
%                    cla(app.RCAxes);
                end
                
                app.Cv7Tv2Label.Text = "---";
                app.Tv2Tv7Label.Text = "---";
                app.Tv7Lv1Label.Text = "---";
                app.Lv1Lv3Label.Text = "---";
                app.Lv3Lv5Label.Text = "---";
            end
        end
               
        function updateSC(app, index)
            colorVid = app.SCColorVid;
            
            app.SCColorImage(:,:,:) = getsnapshot(colorVid);
            app.SCColorImage(:,:,:) = fliplr(app.SCColorImage);
            
            centroid = markerGreenDetector(app.SCColorImage, app.GreenThreshold);
                        
            if length(centroid) == 10 % Bylo detekovano prave 5 markeru
                rankTagColor = tagSCColor(centroid);
                angles = countSCAngles(centroid, rankTagColor);
                
                app.LastSCVal = [
                    centroid(rankTagColor(1)*2+[-1 0]) ...
                    centroid(rankTagColor(2)*2+[-1 0]) ...
                    centroid(rankTagColor(3)*2+[-1 0]) ...
                    centroid(rankTagColor(4)*2+[-1 0]) ...
                    centroid(rankTagColor(5)*2+[-1 0]) ...
                    angles];
                
                if ~isnan(index)
                    app.SaveBuffer(index, 33:48) = [ 
                        app.LastSCVal
                    ];
                end   

            else
                app.LastSCVal = nan(1, 16);
            end
            
            if isnan(index)
                if app.SCColorImShowRes==0
                    app.SCColorImShowRes = imshow(app.SCColorImage, 'Parent', app.SCAxes);
                    hold(app.SCAxes, 'on');
                else
                    set(app.SCColorImShowRes,'Cdata',app.SCColorImage);
                end
            else
                app.SCColorImShowRes = 0;
            end
                
            if ~isempty(app.SCPlotHandles)
                delete(app.SCPlotHandles);
                app.SCPlotHandles = [];
            end

            if length(centroid) == 10 % Bylo detekovano prave 10 markeru
                if isnan(index)
                    rankedX = centroid(rankTagColor*2-1);
                    rankedY = centroid(rankTagColor*2);
                    treeX1 = rankedX([1 2 3 1]);
                    treeY1 = rankedY([1 2 3 1]);
                    treeX2 = rankedX([1 4 5 1]);
                    treeY2 = rankedY([1 4 5 1]);
    
                    app.SCPlotHandles(1) = plot(app.SCAxes, treeX1, treeY1, 'LineWidth', 2, 'Color', [.97 .61 .36]);
                    app.SCPlotHandles(2) = plot(app.SCAxes, treeX2, treeY2, 'LineWidth', 2, 'Color', [.97 .61 .36]);
    
                    for i = 1:length(centroid)/2
                        app.SCPlotHandles(i+2) = plot(app.SCAxes, centroid(rankTagColor(i)*2-1),centroid(rankTagColor(i)*2), '*', 'Color', app.SCMarkerColors{i},'MarkerSize', 6);
                    end
                else
%                    cla(app.SCAxes);
                end

                app.RcajTv7Label.Text = sprintf('%.1f', angles(1));
                app.RcajSxsLabel.Text = sprintf('%.1f', angles(2));
                app.Tv7SxsLabel.Text = sprintf('%.1f', angles(3));
                app.RcajRipsLabel.Text = sprintf('%.1f', angles(4));
                app.RcajRiasLabel.Text = sprintf('%.1f', angles(5));
                app.RipsRiasLabel.Text = sprintf('%.1f', angles(6));
            else                
                app.RcajTv7Label.Text = "---";
                app.RcajSxsLabel.Text = "---";
                app.Tv7SxsLabel.Text = "---";
                app.RcajRipsLabel.Text = "---";
                app.RcajRiasLabel.Text = "---";
                app.RipsRiasLabel.Text = "---";
            end
        end
    
        function stopRecording(app)
            app.Recording = 0;

            app.DepthMapSnapshotButton.Enable = 'on';
                
            app.RecordButton.Text = 'Nahrávat';
                
            app.saveRecord();
                
            if strlength(app.LastRecordFile)>0
                app.OpenLastRecordButton.Enable = 'on';
            end
            
            
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.BBAxes, 'off');
            
            app.GreenThreshold = app.GreenThresholdSlider.Value;
                        
            initRC(app);
            
            initSC(app);
            
            initBB(app);
            
            %initTimer(app);
        end

        % Button pushed function: BBCopyToClipboardButton
        function BBCopyToClipboardButtonPushed(app, event)
            bb1Val = app.LastBB1Val;
            bb2Val = app.LastBB2Val;
            
            [leftCopX, leftCopY] = cop(bb1Val, 1);
            [rightCopX, rightCopY] = cop(bb2Val, 2);
                
            [cogX, cogY] = cog(bb1Val, bb2Val);                
                
            mask = [
                'BbLLT\tBbLLB\tBbLRT\tBbLRB\t' ...
                'BbRLT\tBbRLB\tBbRRT\tBbRRB\t' ...
                'LCopX\tLCopY\tRCopX\tRCopY\t' ...
                'CogX\tCogY' newline() ...
                '%.1f\t%.1f\t%.1f\t%.1f\t'...
                '%.1f\t%.1f\t%.1f\t%.1f\t'...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f'];
            
            strToCopy = sprintf(mask, ...
                bb1Val(4), bb1Val(3), bb1Val(2), bb1Val(1), ...
                bb2Val(1), bb2Val(2), bb2Val(3), bb2Val(4), ...
                leftCopX, leftCopY, rightCopX, rightCopY, ...
                cogX, cogY);
            
            strToCopy = strrep(strToCopy, '.', ',');
            
            clipboard('copy', strToCopy);
        end

        % Button pushed function: OpenLastRecordButton
        function OpenLastRecordButtonPushed(app, event)
            winopen(app.LastRecordFile);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if app.Recording
                app.stopRecording();
            else
                app.ProgressSlider.Value = 0;
                
                app.allocateBuffer();
                
                app.RecBegin = nan;
                
                app.RecordButton.Text = 'Stop';
                
                app.DepthMapSnapshotButton.Enable = 'off';
                
                app.Recording = 1;
            end
        end

        % Value changed function: RecordLengthSpinner
        function RecordLengthSpinnerValueChanged(app, event)
            value = app.RecordLengthSpinner.Value;
            
            app.ProgressSlider.Value = 0;
            app.ProgressSlider.Limits = [0 value];
        end

        % Button pushed function: SCCopyToClipboardButton
        function SCCopyToClipboardButtonPushed(app, event)
            scVal = app.LastSCVal;              
                
            mask = [
                'RCAJX\tRCAJY\tTV7sX\tTV7sY\tSXSX\tSXSY\t' ...
                'RIPSX\tRIPSY\tRIASX\tRIASY\t' ...
                'RCAJ-TV7\tRCAJ-SXS\tTV7-SXS\t'...
                'RCAJ-RIPS\tRCAJ-RIAS\tRIPS-RIAS'...
                newline() ...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.1f\t%.1f\t%.1f\t%.1f\t%.1f\t%.1f'];
            
            strToCopy = sprintf(mask, ...
                scVal(1), scVal(2), scVal(3), scVal(4), ...
                scVal(5), scVal(6), scVal(7), scVal(8), ...
                scVal(9), scVal(10), scVal(11), scVal(12), ...
                scVal(13), scVal(14), scVal(15), scVal(16));
            
            strToCopy = strrep(strToCopy, '.', ',');
            
            clipboard('copy', strToCopy);             
        end

        % Close request function: PosturalUIFigure
        function closeRequest(app, event)
            closeTimer(app);
            
            pause(3);
            
            closeBB(app);
            
            closeRC(app);
            
            closeSC(app);
            
            delete(app);
        end

        % Button pushed function: RCCopyToClipboardButton
        function RCCopyToClipboardButtonPushed(app, event)
            rcVal = app.LastRCVal;              
                
            mask = [
                'CV7X\tCV7Y\tTV2X\tTV2Y\tTV7X\tTV7Y\t' ...
                'LV1X\tLV1Y\tLV3X\tLV3Y\tLV5X\tLV5Y\t' ...
                'CV7-TV2\tTV2-TV7\tTV7-LV1\t'...
                'LV1-LV3\tLV3-LV5' newline() ...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.1f\t%.1f\t%.1f\t%.1f\t%.1f'];
            
            strToCopy = sprintf(mask, ...
                rcVal(1), rcVal(2), rcVal(3), rcVal(4), ...
                rcVal(5), rcVal(6), rcVal(7), rcVal(8), ...
                rcVal(9), rcVal(10), rcVal(11), rcVal(12), ...
                rcVal(13), rcVal(14), rcVal(15), rcVal(16), ...
                rcVal(17));
            
            strToCopy = strrep(strToCopy, '.', ',');
            
            clipboard('copy', strToCopy);            
        end

        % Button pushed function: CopyAllButton
        function CopyAllButtonPushed(app, event)
            bb1Val = app.LastBB1Val;
            bb2Val = app.LastBB2Val;
            rcVal = app.LastRCVal;              
            scVal = app.LastSCVal;              
            
            [leftCopX, leftCopY] = cop(bb1Val, 1);
            [rightCopX, rightCopY] = cop(bb2Val, 2);
                
            [cogX, cogY] = cog(bb1Val, bb2Val);                
                
            mask = [
                'BbLLT\tBbLLB\tBbLRT\tBbLRB\t' ...
                'BbRLT\tBbRLB\tBbRRT\tBbRRB\t' ...
                'LCopX\tLCopY\tRCopX\tRCopY\t' ...
                'CogX\tCogY\t'...
                'CV7X\tCV7Y\tTV2X\tTV2Y\tTV7X\tTV7Y\t' ...
                'LV1X\tLV1Y\tLV3X\tLV3Y\tLV5X\tLV5Y\t' ...
                'CV7-TV2\tTV2-TV7\tTV7-LV1\t'...
                'LV1-LV3\tLV3-LV5\t'...
                'RCAJX\tRCAJY\tTV7sX\tTV7sY\tSXSX\tSXSY\t' ...
                'RIPSX\tRIPSY\tRIASX\tRIASY\t' ...
                'RCAJ-TV7\tRCAJ-SXS\tTV7-SXS\t'...
                'RCAJ-RIPS\tRCAJ-RIAS\tRIPS-RIAS'...
                newline() ...
                '%.1f\t%.1f\t%.1f\t%.1f\t'...
                '%.1f\t%.1f\t%.1f\t%.1f\t'...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.1f\t%.1f\t%.1f\t%.1f\t%.1f\t' ...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.1f\t%.1f\t%.1f\t%.1f\t%.1f\t%.1f'
                ];
            
            strToCopy = sprintf(mask, ...
                bb1Val(4), bb1Val(3), bb1Val(2), bb1Val(1), ...
                bb2Val(1), bb2Val(2), bb2Val(3), bb2Val(4), ...
                leftCopX, leftCopY, rightCopX, rightCopY, ...
                cogX, cogY, ...  
                rcVal(1), rcVal(2), rcVal(3), rcVal(4), ...
                rcVal(5), rcVal(6), rcVal(7), rcVal(8), ...
                rcVal(9), rcVal(10), rcVal(11), rcVal(12), ...
                rcVal(13), rcVal(14), rcVal(15), rcVal(16), ...
                rcVal(17), ...
                scVal(1), scVal(2), scVal(3), scVal(4), ...
                scVal(5), scVal(6), scVal(7), scVal(8), ...
                scVal(9), scVal(10), scVal(11), scVal(12), ...
                scVal(13), scVal(14), scVal(15), scVal(16));
            
            strToCopy = strrep(strToCopy, '.', ',');
            
            clipboard('copy', strToCopy);             
            
        end

        % Button pushed function: CopyMainButton
        function CopyMainButtonPushed(app, event)
                 bb1Val = app.LastBB1Val;
            bb2Val = app.LastBB2Val;
            rcVal = app.LastRCVal;              
            scVal = app.LastSCVal;              
            
            [leftCopX, leftCopY] = cop(bb1Val, 1);
            [rightCopX, rightCopY] = cop(bb2Val, 2);
                
            [cogX, cogY] = cog(bb1Val, bb2Val);                
                
            mask = [
                'LCopX\tLCopY\tRCopX\tRCopY\t' ...
                'CogX\tCogY\t'...
                'CV7-TV2\tTV2-TV7\tTV7-LV1\t'...
                'LV1-LV3\tLV3-LV5\t'...
                'RCAJ-TV7\tRCAJ-SXS\tTV7-SXS\t'...
                'RCAJ-RIPS\tRCAJ-RIAS\tRIPS-RIAS'...
                newline() ...
                '%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t%.0f\t'...
                '%.1f\t%.1f\t%.1f\t%.1f\t%.1f\t' ...
                '%.1f\t%.1f\t%.1f\t%.1f\t%.1f\t%.1f'
                ];
            
            strToCopy = sprintf(mask, ...
                leftCopX, leftCopY, rightCopX, rightCopY, ...
                cogX, cogY, ...  
                rcVal(13), rcVal(14), rcVal(15), rcVal(16), ...
                rcVal(17), ...
                scVal(7), scVal(8), scVal(9), scVal(10), scVal(11), scVal(12));
            
            strToCopy = strrep(strToCopy, '.', ',');
            
            clipboard('copy', strToCopy);            
        end

        % Value changed function: GreenThresholdSlider
        function GreenThresholdSliderValueChanged(app, event)
            app.GreenThreshold = app.GreenThresholdSlider.Value;
        end

        % Button pushed function: DepthMapSnapshotButton
        function DepthMapSnapshotButtonPushed(app, event)
            app.depthMapSnapshot();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PosturalUIFigure = uifigure;
            app.PosturalUIFigure.Name = 'Postural';
            app.PosturalUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.BBPanel = uipanel(app.PosturalUIFigure);
            app.BBPanel.Title = 'Balanční plošina';
            app.BBAxes = uiaxes(app.BBPanel);
            app.BB1LTLabel = uilabel(app.BBPanel);
            app.BB1LTLabel.Text = '---';
            app.BB1LBLabel = uilabel(app.BBPanel);
            app.BB1LBLabel.Text = '---';
            app.BB1RTLabel = uilabel(app.BBPanel);
            app.BB1RTLabel.Text = '---';
            app.BB1RBLabel = uilabel(app.BBPanel);
            app.BB1RBLabel.Text = '---';
            app.BB2LTLabel = uilabel(app.BBPanel);
            app.BB2LTLabel.Text = '---';
            app.BB2LBLabel = uilabel(app.BBPanel);
            app.BB2LBLabel.Text = '---';
            app.BB2RTLabel = uilabel(app.BBPanel);
            app.BB2RTLabel.Text = '---';
            app.BB2RBLabel = uilabel(app.BBPanel);
            app.BB2RBLabel.Text = '---';
            app.BB1SumLabel = uilabel(app.BBPanel);
            app.BB1SumLabel.Text = '---';
            app.BB2SumLabel = uilabel(app.BBPanel);
            app.BB2SumLabel.Text = '---';
            app.BBCopyToClipboardButton = uibutton(app.BBPanel, 'push');
            app.BBCopyToClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @BBCopyToClipboardButtonPushed, true);
            app.BBCopyToClipboardButton.Text = 'Kopírovat';
            app.ZznamPanel = uipanel(app.PosturalUIFigure);
            app.ZznamPanel.Title = 'Záznam';
            app.DlkaLabel = uilabel(app.ZznamPanel);
            app.DlkaLabel.Text = 'Délka';
            app.RecordLengthSpinner = uispinner(app.ZznamPanel);
            app.RecordLengthSpinner.Limits = [0 300];
            app.RecordLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @RecordLengthSpinnerValueChanged, true);
            app.RecordLengthSpinner.Value = 60;
            app.vteinLabel = uilabel(app.ZznamPanel);
            app.vteinLabel.Text = 'vteřin';
            app.ProgressSlider = uislider(app.ZznamPanel);
            app.ProgressSlider.MajorTicks = [];
            app.ProgressSlider.MajorTickLabels = {};
            app.ProgressSlider.MinorTicks = [];
            app.ProgressSlider.Enable = 'off';
            app.OpenLastRecordButton = uibutton(app.ZznamPanel, 'push');
            app.OpenLastRecordButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLastRecordButtonPushed, true);
            app.OpenLastRecordButton.Enable = 'off';
            app.OpenLastRecordButton.Text = 'Otevřít poslední záznam';
            app.RecordButton = uibutton(app.ZznamPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Nahrávat';
            app.RCPanel = uipanel(app.PosturalUIFigure);
            app.RCPanel.Title = 'Zadní kamera';
            app.RCAxes = uiaxes(app.RCPanel);
            app.Label = uilabel(app.RCPanel);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.RCPanel);
            app.Label_2.Text = '°';
            app.Cv7Tv2Caption = uilabel(app.RCPanel);
            app.Cv7Tv2Caption.Text = 'CV7-TV2';
            app.Cv7Tv2Label = uilabel(app.RCPanel);
            app.Cv7Tv2Label.Text = '---';
            app.Cv7Tv2Caption_2 = uilabel(app.RCPanel);
            app.Cv7Tv2Caption_2.Text = 'TV2-TV7';
            app.Tv2Tv7Label = uilabel(app.RCPanel);
            app.Tv2Tv7Label.Text = '---';
            app.Label_9 = uilabel(app.RCPanel);
            app.Label_9.Text = '°';
            app.Label_10 = uilabel(app.RCPanel);
            app.Label_10.Text = '°';
            app.Tv7Lv1Caption = uilabel(app.RCPanel);
            app.Tv7Lv1Caption.Text = 'TV7-LV1';
            app.Tv7Lv1Label = uilabel(app.RCPanel);
            app.Tv7Lv1Label.Text = '---';
            app.Lv1Lv3Caption = uilabel(app.RCPanel);
            app.Lv1Lv3Caption.Text = 'LV1-LV3';
            app.Lv1Lv3Label = uilabel(app.RCPanel);
            app.Lv1Lv3Label.Text = '---';
            app.Label_11 = uilabel(app.RCPanel);
            app.Label_11.Text = '°';
            app.Lv3Lv5Caption = uilabel(app.RCPanel);
            app.Lv3Lv5Caption.Text = 'LV3-LV5';
            app.Lv3Lv5Label = uilabel(app.RCPanel);
            app.Lv3Lv5Label.Text = '---';
            app.RCCopyToClipboardButton = uibutton(app.RCPanel, 'push');
            app.RCCopyToClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @RCCopyToClipboardButtonPushed, true);
            app.RCCopyToClipboardButton.Text = 'Kopírovat';
            app.DepthMapSnapshotButton = uibutton(app.RCPanel, 'push');
            app.DepthMapSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @DepthMapSnapshotButtonPushed, true);
            app.DepthMapSnapshotButton.Text = 'Snímek';
            app.SCPanel = uipanel(app.PosturalUIFigure);
            app.SCPanel.Title = 'Boční kamera';
            app.SCAxes = uiaxes(app.SCPanel);
            app.Label_12 = uilabel(app.SCPanel);
            app.Label_12.Text = '°';
            app.Label_13 = uilabel(app.SCPanel);
            app.Label_13.Text = '°';
            app.RcajRiasCaption = uilabel(app.SCPanel);
            app.RcajRiasCaption.Text = 'RCAJ-TV7';
            app.RcajTv7Label = uilabel(app.SCPanel);
            app.RcajTv7Label.Text = '---';
            app.RcajRipsCaption = uilabel(app.SCPanel);
            app.RcajRipsCaption.Text = 'RCAJ-SXS';
            app.RcajSxsLabel = uilabel(app.SCPanel);
            app.RcajSxsLabel.Text = '---';
            app.Label_14 = uilabel(app.SCPanel);
            app.Label_14.Text = '°';
            app.RiasRipsCaption = uilabel(app.SCPanel);
            app.RiasRipsCaption.Text = 'TV7-SXS';
            app.Tv7SxsLabel = uilabel(app.SCPanel);
            app.Tv7SxsLabel.Text = '---';
            app.SCCopyToClipboardButton = uibutton(app.SCPanel, 'push');
            app.SCCopyToClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @SCCopyToClipboardButtonPushed, true);
            app.SCCopyToClipboardButton.Text = 'Kopírovat';
            app.PrhSliderLabel = uilabel(app.SCPanel);
            app.PrhSliderLabel.Text = 'Práh';
            app.GreenThresholdSlider = uislider(app.SCPanel);
            app.GreenThresholdSlider.Limits = [0 60];
            app.GreenThresholdSlider.MajorTickLabels = {'', '', '', '', '', '', ''};
            app.GreenThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @GreenThresholdSliderValueChanged, true);
            app.GreenThresholdSlider.Value = 20;
            app.Label_15 = uilabel(app.SCPanel);
            app.Label_15.Text = '°';
            app.RcajRiasCaption_2 = uilabel(app.SCPanel);
            app.RcajRiasCaption_2.Text = 'RCAJ-RIAS';
            app.RcajRiasLabel = uilabel(app.SCPanel);
            app.RcajRiasLabel.Text = '---';
            app.RcajRipsCaption_2 = uilabel(app.SCPanel);
            app.RcajRipsCaption_2.Text = 'RIPS-RIAS';
            app.RipsRiasLabel = uilabel(app.SCPanel);
            app.RipsRiasLabel.Text = '---';
            app.Label_16 = uilabel(app.SCPanel);
            app.Label_16.Text = '°';
            app.RiasRipsCaption_2 = uilabel(app.SCPanel);
            app.RiasRipsCaption_2.Text = 'RCAJ-RIPS';
            app.RcajRipsLabel = uilabel(app.SCPanel);
            app.RcajRipsLabel.Text = '---';
            app.Label_17 = uilabel(app.SCPanel);
            app.Label_17.Text = '°';
            app.RCLegend = uiaxes(app.PosturalUIFigure);
            title(app.RCLegend, 'Title')
            xlabel(app.RCLegend, 'X')
            ylabel(app.RCLegend, 'Y')
            app.KoprovatPanel = uipanel(app.PosturalUIFigure);
            app.KoprovatPanel.Title = 'Kopírovat';
            app.CopyMainButton = uibutton(app.KoprovatPanel, 'push');
            app.CopyMainButton.ButtonPushedFcn = createCallbackFcn(app, @CopyMainButtonPushed, true);
            app.CopyMainButton.Text = 'Hlavní';
            app.CopyAllButton = uibutton(app.KoprovatPanel, 'push');
            app.CopyAllButton.ButtonPushedFcn = createCallbackFcn(app, @CopyAllButtonPushed, true);
            app.CopyAllButton.Text = 'Vše';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Postural

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PosturalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PosturalUIFigure)
        end
    end
end